classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        RESULTSPanel                    matlab.ui.container.Panel
        NeutralTerritoryEditField       matlab.ui.control.EditField
        NeutralTerritoryEditFieldLabel  matlab.ui.control.Label
        WhiteTerritoryEditField         matlab.ui.control.EditField
        WhiteTerritoryEditFieldLabel    matlab.ui.control.Label
        BlackTerritoryEditField         matlab.ui.control.EditField
        BlackTerritoryEditFieldLabel    matlab.ui.control.Label
        WhiteStonesEditField            matlab.ui.control.EditField
        WhiteStonesEditFieldLabel       matlab.ui.control.Label
        BlackStonesEditField            matlab.ui.control.EditField
        BlackStonesEditFieldLabel       matlab.ui.control.Label
        INPUTPanel                      matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        ImageAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        % save original image file in currentInput
        % save processed (grayscaled, etc) image in processedInput
        % save input image original file type in inputFileType
        % save processed edge image in currentEdge
        % save current processed output in currentOutput
        % save last saved mask size in maskSize
        currentInput = [];
        currentOutput = [];
        inputFileType = [];
    end
    
    methods (Access = private)       
        function updateimage(app)
            im = app.currentInput;
            imagesc(app.ImageAxes, im);

            % Update output
            updateoutput(app);
        end            
        
        function updateoutput(app)
            % Get all points from image
            output = app.currentInput;
  
            % Display result
            imagesc(app.ImageAxes,output);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');

            % Update the image
            [indexedImage, map] = imread('default.png');
            rgbImage = ind2rgb(indexedImage, map);
            app.currentInput = rgbImage;
            updateimage(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.png','All Image Files'};
            fig = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [f, p] = uigetfile(filterspec);
            delete(fig);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               try
                   [~,~,ext] = fileparts(fname);
                   app.inputFileType = ext;
                   if (ext == '.png')
                       [indexedImage, map] = imread(fname);
                       rgbImage = ind2rgb(indexedImage, map);
                       uialert(app.UIFigure, size(rgbImage), 'Image Error');
                       app.currentInput = rgbImage;
                   else
                       im = imread(fname);
                       app.currentInput = im;
                   end
                   updateimage(app);

               catch ME
                   % If problem reading image, display error message
                   uialert(app.UIFigure, ME.message, 'Image Error');
                   return;
               end
               figure(app.UIFigure);
               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Go Score Counter';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.INPUTPanel = uipanel(app.UIFigure);
            app.INPUTPanel.Title = 'INPUT';
            app.ImageAxes = uiaxes(app.INPUTPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.INPUTPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.RESULTSPanel = uipanel(app.UIFigure);
            app.RESULTSPanel.Title = 'RESULTS';
            app.BlackStonesEditFieldLabel = uilabel(app.RESULTSPanel);
            app.BlackStonesEditFieldLabel.Text = 'Black Stones';
            app.BlackStonesEditField = uieditfield(app.RESULTSPanel, 'text');
            app.BlackStonesEditField.Editable = 'off';
            app.WhiteStonesEditFieldLabel = uilabel(app.RESULTSPanel);
            app.WhiteStonesEditFieldLabel.Text = 'White Stones';
            app.WhiteStonesEditField = uieditfield(app.RESULTSPanel, 'text');
            app.WhiteStonesEditField.Editable = 'off';
            app.BlackTerritoryEditFieldLabel = uilabel(app.RESULTSPanel);
            app.BlackTerritoryEditFieldLabel.Text = 'Black Territory';
            app.BlackTerritoryEditField = uieditfield(app.RESULTSPanel, 'text');
            app.BlackTerritoryEditField.Editable = 'off';
            app.WhiteTerritoryEditFieldLabel = uilabel(app.RESULTSPanel);
            app.WhiteTerritoryEditFieldLabel.Text = 'White Territory';
            app.WhiteTerritoryEditField = uieditfield(app.RESULTSPanel, 'text');
            app.WhiteTerritoryEditField.Editable = 'off';
            app.NeutralTerritoryEditFieldLabel = uilabel(app.RESULTSPanel);
            app.NeutralTerritoryEditFieldLabel.Text = 'Neutral Territory';
            app.NeutralTerritoryEditField = uieditfield(app.RESULTSPanel, 'text');
            app.NeutralTerritoryEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end